classdef ARES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout19                    matlab.ui.container.GridLayout
        MainTabs                        matlab.ui.container.TabGroup
        PowerAmplifierTab               matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        InstrumentConnectionsPanel      matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        SpectrumAnalyzerDropDown        matlab.ui.control.DropDown
        SpectrumAnalyzerDropDownLabel   matlab.ui.control.Label
        SignalGeneratorDropDown         matlab.ui.control.DropDown
        SignalGeneratorDropDownLabel    matlab.ui.control.Label
        PowerSupplyDropDown             matlab.ui.control.DropDown
        PowerSupplyDropDownLabel        matlab.ui.control.Label
        PAConnectButton                 matlab.ui.control.Button
        PAScanButton                    matlab.ui.control.Button
        PASettingsPanel                 matlab.ui.container.TabGroup
        PowerSupplyTab                  matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        SupplyDropDown                  matlab.ui.control.DropDown
        SupplyDropDownLabel             matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        SupplyVoltageStep               matlab.ui.control.NumericEditField
        SupplyVoltageStepVEditField     matlab.ui.control.Label
        SupplyVoltageStop               matlab.ui.control.NumericEditField
        SupplyVoltageStopVEditField     matlab.ui.control.Label
        SupplyVoltage                   matlab.ui.control.NumericEditField
        SupplyVoltageVEditField         matlab.ui.control.Label
        SignalGeneratorTab              matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        PowerStep                       matlab.ui.control.NumericEditField
        EndPower                        matlab.ui.control.NumericEditField
        StartPower                      matlab.ui.control.NumericEditField
        FrequencyStep                   matlab.ui.control.NumericEditField
        EndFrequency                    matlab.ui.control.NumericEditField
        StartFrequency                  matlab.ui.control.NumericEditField
        PowerStepdBmEditField           matlab.ui.control.Label
        EndPowerdBmEditFieldLabel       matlab.ui.control.Label
        StartPowerdBmEditFieldLabel     matlab.ui.control.Label
        FrequencyStepMHzLabel           matlab.ui.control.Label
        StartFrequencyMHzLabel          matlab.ui.control.Label
        EndFrequencyMHzLabel            matlab.ui.control.Label
        SpectrumAnalyzerTab             matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        CenterFrequency                 matlab.ui.control.NumericEditField
        CenterFrequencyMHzEditField     matlab.ui.control.Label
        Span                            matlab.ui.control.NumericEditField
        SweepPoints                     matlab.ui.control.NumericEditField
        ReferenceLevel                  matlab.ui.control.NumericEditField
        SpanMHzEditField                matlab.ui.control.Label
        SweepPointsEditField            matlab.ui.control.Label
        ReferenceLeveldBEditField       matlab.ui.control.Label
        MeasurementPanel                matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        MeasurementDropDown             matlab.ui.control.DropDown
        MeasurementDropDownLabel        matlab.ui.control.Label
        PlotPAData                      matlab.ui.control.Button
        SavePAData                      matlab.ui.control.Button
        LoadPAData                      matlab.ui.control.Button
        StartPAMeasurement              matlab.ui.control.Button
        ResultsTabs                     matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        SingleGain                      matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SweepPeakDEPAE                  matlab.ui.control.UIAxes
        SweepCompressionPoints          matlab.ui.control.UIAxes
        SweepPeakGain                   matlab.ui.control.UIAxes
        SweepGain                       matlab.ui.control.UIAxes
        AntennaTab                      matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        TestandPlotSettingsPanel        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        StopTestButton                  matlab.ui.control.Button
        LoadResultsButton               matlab.ui.control.Button
        PlotResultsButton               matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        PlottingAngleDropDown           matlab.ui.control.DropDown
        PlottingAngleDropDownLabel      matlab.ui.control.Label
        PlottingFrequencyDropDown       matlab.ui.control.DropDown
        PlottingFrequencyDropDownLabel  matlab.ui.control.Label
        InstrumentConnectionsPanel_2    matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        EMCenterDropDown                matlab.ui.control.DropDown
        EMCenterDropDownLabel           matlab.ui.control.Label
        VNADropDown                     matlab.ui.control.DropDown
        VNADropDownLabel                matlab.ui.control.Label
        AntennaConnectButton            matlab.ui.control.Button
        AntennaScanButton               matlab.ui.control.Button
        AntennaSettings                 matlab.ui.container.TabGroup
        VNATab                          matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        SweepPointsLabel                matlab.ui.control.Label
        MeasurementDropDown_2Label      matlab.ui.control.Label
        VNAEndFrequency                 matlab.ui.control.NumericEditField
        VNAStartFrequency               matlab.ui.control.NumericEditField
        VNASweepPoints                  matlab.ui.control.NumericEditField
        VNAMeasurement                  matlab.ui.control.DropDown
        EndFrequencyMHzLabel_6          matlab.ui.control.Label
        StartFrequectSpanMHzLabel_15    matlab.ui.control.Label
        Table                           matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        MovetoAngleButton               matlab.ui.control.Button
        TableSpeedSlider                matlab.ui.control.Slider
        TableSpeedSliderLabel           matlab.ui.control.Label
        TableNewAngle                   matlab.ui.control.NumericEditField
        StopTableButton                 matlab.ui.control.Button
        TableEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_13    matlab.ui.control.Label
        TableStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_12    matlab.ui.control.Label
        TableStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_11    matlab.ui.control.Label
        TowerTab                        matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        MoveTowertoAngleButton          matlab.ui.control.Button
        TowerNewAngle                   matlab.ui.control.NumericEditField
        TowerSpeedSlider                matlab.ui.control.Slider
        TowerSpeedSliderLabel           matlab.ui.control.Label
        StopTowerButton                 matlab.ui.control.Button
        TowerEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_18    matlab.ui.control.Label
        TowerStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_17    matlab.ui.control.Label
        TowerStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_16    matlab.ui.control.Label
        LinearSliderTab                 matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        AntennaPhysicalSize             matlab.ui.control.NumericEditField
        AntennaOffsetLabel              matlab.ui.control.Label
        LinearSliderNewPosition         matlab.ui.control.NumericEditField
        MovetoPositionButton            matlab.ui.control.Button
        LinearSliderCurrentPosition     matlab.ui.control.NumericEditField
        SliderPositioncmLabel           matlab.ui.control.Label
        AntennaCurrentSpacing           matlab.ui.control.NumericEditField
        CurrentSpacingLabel             matlab.ui.control.Label
        SpeedPresetDropDown             matlab.ui.control.DropDown
        SpeedPresetDropDownLabel        matlab.ui.control.Label
        StopLinearSliderButton          matlab.ui.control.Button
        AntennaPattern                  matlab.ui.container.TabGroup
        MeasurementAntennaGainTab       matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        BrowseReferenceFileButton       matlab.ui.control.Button
        ReferenceGainFileField          matlab.ui.control.EditField
        PlotReferenceGainButton         matlab.ui.control.Button
        ReturnLossBoresightGain         matlab.ui.control.UIAxes
        GainvsFrequencyBoresightGain    matlab.ui.control.UIAxes
        DPatternMeasurementTab          matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GainvsAngle2D                   matlab.ui.control.UIAxes
        GainvsFrequency2D               matlab.ui.control.UIAxes
        ReturnLoss2D                    matlab.ui.control.UIAxes
        InfoTab                         matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        Hyperlink2_3                    matlab.ui.control.Hyperlink
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        V01BETARelaseLabel              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        %% App Properties
        EXCEL_MAX_ROWS = 1048576;  % Maximum number of rows in Excel
        EXCEL_MAX_COLUMNS = 16384; % Maximum number of columns in Excel
        StopRequested = false;     % Boolean flag: true if user requested a measurement to stop, false otherwise.
        RadiationPlot2D

        %% Instrument Objects
        PowerSupply                % Power Supply 
        SignalGenerator            % Signal Generator
        SpectrumAnalyzer           % Spectrum Analyzer
        VNA                        % Vector Network Analyzer
        EMCenter                   % Anechoic Chamber Control  
        LinearSlider               % Linear Slider

        %% Power Amplifier Properties
        mType                      % PA measurement type: single or sweep frequencies
        inputFrequencySingle       % Frequency scalar for single measurement (Hz)
        inputPdBmSingle            % RF Input Power for single measurement (dBm)
        inputFrequencySweep        % Frequency array for sweep measurement (Hz)
        inputPdBmSweep             % RF Input Power for sweep ,easurement (dBm)
        outputPdBm                 % RF Output Power (dBm)
        DCPower                    % DC Bias Power (W)
        PAGain                     % Power Amplifier - Gain (dB)
        DE                         % Drain Efficiency (%)
        PAE                        % Power-Added Efficiency (%)

        %% Antenna Properties
        % Plot Properties
        azimuthAngles              % Measured azimuth angles (deg)
        elevationAngles            % Measured elevation anges (deg)
        sweepFrequencies           % Measured VNA sweep frequencies (Hz)
        antennaRL                  % Measured antenna return loss (dB)
        antennaGain                % Measured antenna gain (dBi)
        selectedAngle              % User selected angle to plot
        selectedFrequency          % User selected frequency to plot 
        angleRange                 % 
        frequencyRange

        % Measurement Properties
        ReferenceGainFile          %
        AzimuthAngles 
        TestFrequencies 
        S11
        S21 
        S22
        AntennaGain_dBi
        offsetSpacing = 0.8062;    % Spacing between antennas (m)
        linearSpacing              % Spacing added by linear slider (m)
        setupSpacing               % Total spacing between antennas accounting for LS and offset (m)
    end
   
    
    methods (Access = private)

        function getInstrumentResources(app, instrumentNames)
            % This function scans for connected VISA resources, retrieves their resource names, and dynamically updates the dropdown menus 
            % for the specified instruments. It clears existing menu items and adds new ones, including a "None" option, allowing the user 
            % to choose from currently available instruments or leave the selection blank.

            app.AntennaScanButton.Enable = 'off';
            try 
                % Retrieve the list of VISA resources with a specified timeout
                timeout = 40; % seconds
                resourceList = visadevlist("Timeout", timeout);
                allResources = resourceList.ResourceName';
            
                % Clear dropdown items for each instrument 
                for i = 1:numel(instrumentNames)
                    dropdownFieldName = strcat(instrumentNames{i}, 'DropDown');
                    if isprop(app, dropdownFieldName) 
                        app.(dropdownFieldName).Items = {};
                    end
                end
     
                % Prepare the dropdown items (with "None" option)
                dropdownItems = [allResources, "None"];

                % Assign scanned addresses to the dropdown menus
                for i = 1:numel(allResources)
                    if i > numel(instrumentNames)
                        break
                    else
                        dropdownFieldName = strcat(instrumentNames{i}, 'DropDown');
                        app.(dropdownFieldName).Items = dropdownItems;
                    end
                end
            catch ME
                app.displayError(ME);
            end
            app.AntennaScanButton.Enable = 'on';
        end
        
        function connectInstrument(app, instrumentNames, instrumentResources)
            % This function checks if the connection to the instrument is
            % valid, and connects the specified address of the instrument.   
            for i = 1:numel(instrumentNames)
                instrumentName = instrumentNames{i};
                instrumentResource = instrumentResources{i};
                dropdownFieldName = strcat(instrumentName, 'DropDown');
                
                % Check if the dropdown is enabled, indicating no existing connection
                if strcmp(app.(dropdownFieldName).Enable, 'on')
                    if ~isempty(instrumentResource)
                        try
                            app.(instrumentName) = visadev(instrumentResource);
                            if isvalid(app.(instrumentName))
                                app.(instrumentName).ByteOrder = 'little-endian';
                                app.(dropdownFieldName).Enable = 'off';
                            else
                                error('Invalid VISA object');
                            end
                        catch ME
                            uialert(app.UIFigure, sprintf('Failed to connect to %s: %s.', instrumentResource, ME.message), 'Error', 'Icon', 'warning');
                        end
                    end
                else
                    continue
                end
            end
        end
    
        function runPAMeasurement(app, measurementType)
            % This function runs either the sweep frequency measurement or the single
            % frequency measurement based on the measurementType parameter.
            try
                supplyVoltage = app.SupplyVoltage.Value;
                if supplyVoltage > 25 || supplyVoltage < -25
                    error('Voltage level outside of power supply range');
                end
                writeline(app.PowerSupply, '*RST');
                writeline(app.PowerSupply, ':SYSTem:REMote');
                writeline(app.PowerSupply, sprintf(':APPLy %s,%g,%s', 'P25V', supplyVoltage, 'MAXimum'));
            catch errorObj
                errordlg(errorObj.message, 'Power Supply Error');
                return;
            end
        
            if strcmp(measurementType, 'Sweep')
                app.inputFrequencySweep = app.StartFrequency.Value*1E6:app.FrequencyStep.Value*1E6:app.EndFrequency.Value*1E6;
                app.inputPdBmSweep = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;
                app.outputPdBm = zeros(1, length(app.inputPdBmSweep));
                app.DCPower = zeros(1, length(app.inputPdBmSweep));
            else
                app.inputFrequencySingle = app.StartFrequency.Value * 1E6;
                app.inputPdBmSingle = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;
                app.outputPdBm = zeros(1, length(app.inputPdBmSingle));
                app.DCPower = zeros(1, length(app.inputPdBmSingle));
            end

            sweepPoints = app.SweepPointsSingle.Value;
            refLevel = app.ReferenceLevelSingle.Value;
        
            % Reset the signal analyzer
            writeline(app.SpectrumAnalyzer, '*RST');
            writeline(app.SpectrumAnalyzer, sprintf(':SENSe:SWEep:POINts %d', sweepPoints));
            writeline(app.SpectrumAnalyzer, sprintf(':DISPlay:WINDow:TRACe:Y:SCALe:RLEVel %g', refLevel));
            writeline(app.SpectrumAnalyzer, sprintf(':FORMat:TRACe:DATA %s,%d', 'REAL', 64));
            writeline(app.SpectrumAnalyzer, sprintf(':FORMat:BORDer %s', 'SWAPped'));
        
            % Reset the signal generator
            writeline(app.SignalGenerator, '*RST');
            writeline(app.SignalGenerator, sprintf(':OUTPut:STATe %d', 1));
        
            % Turn on the power supply
            writeline(app.PowerSupply, sprintf(':OUTPut:STATe %d', 1));
        
            if strcmp(measurementType, 'Sweep')
                for i = 1:length(app.inputFrequencySweep)
                    center_Freq = app.inputFrequencySweep(i);
                    span = app.inputFrequencySweep(i) / 2;
                    writeline(app.SignalGenerator, sprintf(':SOURce:FREQuency:CW %d', app.inputFrequencySweep(i)));
                    writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:CENTer %g', center_Freq));
                    writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:SPAN %g', span));
        
                    for j = 1:length(app.inputPdBmSweep)
                        [app.outputPdBm(i, j), app.DCPower(i, j)] = measureRFOutput(app.SignalGenerator, app.SpectrumAnalyzer, app.PowerSupply, app.inputPdBmSweep(j));
                    end
                end
            else
                writeline(app.SignalGenerator, sprintf(':SOURce:FREQuency:CW %d', app.inputFrequencySingle));
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:CENTer %g', app.CenterFrequency.Value * 1E6));
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:SPAN %g', app.Span.Value * 1E6));
        
                for i = 1:length(app.inputPdBmSingle)
                    [app.outputPdBm(i), app.DCPower(i)] = measureRFOutput(app.SignalGenerator, app.SpectrumAnalyzer, app.PowerSupply, app.inputPdBmSingle(i));
                end
            end
        
            % Turn off the power supply
            writeline(app.PowerSupply, sprintf(':OUTPut:STATe %d', 0));
        
            % Turn off the signal generator
            writeline(app.SignalGenerator, sprintf(':SOURce:POWer:LEVel:IMMediate:AMPLitude %s', 'MIN'));
            writeline(app.SignalGenerator, sprintf(':OUTPut:STATe %d', 0));
            writeline(app.SignalGenerator, sprintf(':OUTPut:MODulation:STATe %d', 0));
        
            % Calculate Gain, DE, and PAE
            if strcmp(measurementType, 'Sweep')
                [app.PAGain, app.DE, app.PAE] = measureRFParameters(app.inputPdBmSweep, app.outputPdBm, app.DCPower);
            else
                [app.PAGain, app.DE, app.PAE] = measureRFParameters(app.inputPdBmSingle, app.outputPdBm, app.DCPower);
            end
        end

        function peakValues = measureRFpeaks(app)
            % This function is used with the sweep type measurement, it
            % calculates the peak values of several RF paramters (Gain, DE,
            % PAE, Saturation Power, -1dB and -3dB compression points).

            peak_Gain = zeros(size(app.inputFrequencySweep, 1), 1);
            peak_1dB  = zeros(size(app.inputFrequencySweep, 1), 1);
            peak_3dB  = zeros(size(app.inputFrequencySweep, 1), 1);
        
            % Window size for the moving average
            windowSize = 3; 
            
            for i = 1:size(app.inputFrequencySweep, 1)
                smoothedGain = movmean(app.PAGain(i, :), windowSize);
                peak_Gain(i) = max(smoothedGain);
            
                % Find the -1dB compression point
                idx_1dB = find(smoothedGain <= (peak_Gain(i) - 1), 1, 'first');
                if ~isempty(idx_1dB)
                    peak_1dB(i) = app.outputPdBm(i, idx_1dB);
                else
                    peak_1dB(i) = NaN;
                end
            
                % Find the -3dB compression point
                idx_3dB = find(smoothedGain <= (peak_Gain(i) - 3), 1, 'first');
                if ~isempty(idx_3dB)
                    peak_3dB(i) = app.outputPdBm(i, idx_3dB);
                else
                    peak_3dB(i) = NaN;
                end
            end
        
            saturation_PdBm = max(app.outputPdBm, [], 2);
            peak_DE  = max(app.DE, [], 2);
            peak_PAE = max(app.PAE, [], 2);
        
            peakValues = {saturation_PdBm, peak_Gain, peak_DE, peak_PAE, peak_1dB', peak_3dB'};
        end

        function plotPASingleMeasurement(app)
            % This function plots the data from the single measurement
            % type. The first plot shows input power vs. output power. The
            % second plot shows the DE, PAE, gain, and compression points
            % if found.
            cla(app.SingleGain);

            % Window size for the moving average
            % REMOVE ONCE POWER SUPPLY IS FIXED
            windowSize = 3; 
            smoothedGain = movmean(app.PAGain, windowSize);
            maxGain = max(smoothedGain);
            idx_1dB = find(smoothedGain <= (maxGain - 1), 1, 'first');
            idx_3dB = find(smoothedGain <= (maxGain - 3), 1, 'first'); 
            [saturation_power, saturation_idx] = max(app.outputPdBm);

            % Plot Gain on the left y-axis
            yyaxis(app.SingleGain, 'left');
            h1 = plot(app.SingleGain, app.outputPdBm, app.PAGain, 'black');
            hold(app.SingleGain, 'on');

            % Plot the compression points
            if ~isempty(idx_1dB)
                h4 = plot(app.SingleGain, app.outputPdBm(idx_1dB), app.PAGain(idx_1dB), 'blueo', 'MarkerSize', 10);
            end
            if ~isempty(idx_3dB)
                h5 = plot(app.SingleGain, app.outputPdBm(idx_3dB), app.PAGain(idx_3dB), 'redo', 'MarkerSize', 10);
            end
            if ~isempty(saturation_idx)
                h6 = plot(app.SingleGain, saturation_power, app.PAGain(saturation_idx), 'greeno', 'MarkerSize', 10);
            end
            ylabel(app.SingleGain, 'Gain (dB)');

            % Plot DE and PAE on the right y-axis
            yyaxis(app.SingleGain, 'right');
            h2 = plot(app.SingleGain, app.outputPdBm, app.DE, 'blue-');
            h3 = plot(app.SingleGain, app.outputPdBm, app.PAE, 'red--');
            ylabel(app.SingleGain, 'Efficiency (%)');

            hold(app.SingleGain, 'off');
            xlabel(app.SingleGain, 'Output Power (dBm)');
            title(app.SingleGain, 'Gain, DE, and PAE vs. Output Power');

            % Legend order
            legendEntries = {'Gain', 'DE', 'PAE'};
            legendHandles = [h1, h2, h3];
            if exist('h4', 'var')
                legendHandles(end+1) = h4; 
                legendEntries{end+1} = 'Gain_{-1dB}'; 
            end
            if exist('h5', 'var')
                legendHandles(end+1) = h5; 
                legendEntries{end+1} = 'Gain_{-3dB}'; 
            end
            if exist('h6', 'var')
                legendHandles(end+1) = h6; 
                legendEntries{end+1} = 'Saturation'; 
            end
            legend(app.SingleGain, legendHandles, legendEntries, 'Location', 'west');
            
            improveAxesAppearance(app.SingleGain, 'true');   
        end

        function plotPASweepMeasurement(app)
            % This function plots the data from the sweep measurement type.
            % The first plots shows gain vs. output power. Peak DE, PAE,
            % and gain are also plotted for each frequency. Finally the
            % saturation, and compression points are plotted. If no curve
            % appears for the compression points then there was a frequency
            % with no compression.
            cla(app.SweepGain);
            cla(app.SweepCompressionPoints);
            cla(app.SweepPeakGain);
            cla(app.SweepPeakDEPAE);

            % Get peak values
            peakValues = app.measureRFpeaks();          
            
            % Plot Gain versus output power over the frequency sweep
            hold(app.SweepGain, 'on'); 
            for i = 1:size(app.outputPdBm, 1)
                plot(app.SweepGain, app.outputPdBm(i, :), app.PAGain(i, :));
            end
            hold(app.SweepGain, 'off');
            title(app.SweepGain, 'Gain vs. Output Power');
            xlabel(app.SweepGain, 'Output Power (dBm)');
            ylabel(app.SweepGain, 'Gain (dB)');
            
            % % Plot peak Gain over the frequency sweep
            plot(app.SweepCompressionPoints, app.inputFrequencySweep(1:size(app.outputPdBm, 1))/1E9, peakValues{2}(1:size(app.outputPdBm, 1)), 'blue');
            title(app.SweepCompressionPoints, 'Peak Gain');
            xlabel(app.SweepCompressionPoints, 'Frequency (GHz)');
            ylabel(app.SweepCompressionPoints, 'Gain (dB)');
            
            % Plot peak DE and peak PAE over the frequency sweep
            hold(app.SweepPeakGain, 'on');
            h1 = plot(app.SweepPeakGain, app.inputFrequencySweep(1:size(app.outputPdBm, 1))/1E9, peakValues{3}(1:size(app.outputPdBm, 1)), 'blue');
            h2 = plot(app.SweepPeakGain, app.inputFrequencySweep(1:size(app.outputPdBm, 1))/1E9, peakValues{4}(1:size(app.outputPdBm, 1)), 'red');
            hold(app.SweepPeakGain, 'off');
            title(app.SweepPeakGain, 'Peak DE and PAE');
            xlabel(app.SweepPeakGain, 'Frequency (GHz)');
            ylabel(app.SweepPeakGain, 'Efficiency (%)');
            legend(app.SweepPeakGain, [h1, h2], 'DE', 'PAE', 'Location', 'best');

            % Plot saturation power and compression points over the
            % frequency sweep, if a compression point was not found, 
            % nothing is plotted.
            plotHandles = {};
            plotColors = {'black', 'red', 'blue'};
            xData = {[], [], []};
            yData = {[], [], []};
            legendLabels = {'P_{sat}', 'P_{-1dB}', 'P_{-3dB}'};
            hold(app.SweepPeakDEPAE, 'on'); 

            %Loop through data and collect non-zero points
            for i = 1:size(app.outputPdBm, 1)
                if peakValues{1}(i) ~= 0
                    xData{1} = [xData{1}, app.inputFrequencySweep(i)/1E9];
                    yData{1} = [yData{1}, peakValues{1}(i)];
                end
                if peakValues{5}(i) ~= 0
                    xData{2} = [xData{2}, app.inputFrequencySweep(i)/1E9];
                    yData{2} = [yData{2}, peakValues{5}(i)];
                end
                if peakValues{6}(i) ~= 0
                    xData{3} = [xData{3}, app.inputFrequencySweep(i)/1E9];
                    yData{3} = [yData{3}, peakValues{6}(i)];
                end   
            end
            
            for idx = 1:3
                if ~isempty(xData{idx})
                    plotHandles{end+1} = plot(app.SweepPeakDEPAE, xData{idx}, yData{idx}, [plotColors{idx}, '-']);
                    plot(app.SweepPeakDEPAE, xData{idx}, yData{idx}, plotColors{idx});
                end
            end
            ylabel(app.SweepPeakDEPAE, 'Output Power (dBm)');
            xlabel(app.SweepPeakDEPAE, 'Frequency (GHz)');
            title(app.SweepPeakDEPAE, 'Saturation Power and Compression Points');

            if ~isempty(plotHandles)
                legend(app.SweepPeakDEPAE, [plotHandles{:}], legendLabels(1:length(plotHandles)), 'Location', 'best');
            end
            hold(app.SweepPeakDEPAE, 'off');

            improveAxesAppearance(app.SweepGain, 'YYAxis', 'false', 'LineThickness', 2.5);
            improveAxesAppearance(app.SweepCompressionPoints, 'YYAxis', 'false', 'LineThickness', 2.5);
            improveAxesAppearance(app.SweepPeakGain, 'YYAxis', 'false', 'LineThickness', 2.5);
            improveAxesAppearance(app.SweepPeakDEPAE, 'YYAxis', 'false', 'LineThickness', 2.5);
        end

        function getBoresightGain(app, startFrequency, endFrequency, sweepPoints)
            try
                [SParameters, VNAFreqs] = measureSParameters(app.VNA, 2, startFrequency, endFrequency, sweepPoints);
                BoresightGain_dBi = measureAntennaGain(VNAFreqs, SParameters{2}, app.setupSpacing);
                combinedData = [double(VNAFreqs)', double(BoresightGain_dBi)', double(SParameters{3})'];
                combinedNames = {'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
                saveData(combinedData, combinedNames, false);
            catch ME
                app.displayError(ME);
            end
        end

        function get2DAntennaGain(app, startFrequency, endFrequency, sweepPoints) 
            app.StopRequested = false;

            if ~isempty(app.ReferenceGainFile)
                ReferenceFreqs = app.ReferenceGainFile.FrequencyHz;
                ReferenceGain = app.ReferenceGainFile.GaindBi;
            end

            if isempty(app.TableSpeedSlider.Value)
                error('Table Speed cannot be empty.');
            elseif isempty(app.TableStartAngle.Value)
                error('Table Start Angle cannot be empty.');
            elseif isempty(app.TableStepAngle.Value)
                error('Table Step Angle cannot be empty.');
            elseif isempty(app.TableEndAngle.Value)
                error('Table End Angle cannot be empty.');
            else
                tableSpeed = app.TableSpeedSlider.Value;
                tableAngles = app.TableStartAngle.Value:app.TableStepAngle.Value:app.TableEndAngle.Value;
                arraySize = sweepPoints * length(tableAngles);
            end
            
            app.S11 = zeros(1, arraySize);
            app.S21 = zeros(1, arraySize);
            app.S22 = zeros(1, arraySize); 
            app.AzimuthAngles = zeros(1, arraySize);
            app.TestFrequencies = zeros(1, arraySize);
            app.AntennaGain_dBi = zeros(1, arraySize);
      
            try
                % Set speed of the turntable
                writeline(app.EMCenter, sprintf('1A:SPEED %d', tableSpeed));

                for i = 1:length(tableAngles)
                    dataPts = (i-1)*sweepPoints + (1:sweepPoints);
                    adjustedAzimuthAngle = mod(tableAngles(i), 360);

                    % Move table to specified angle
                    writeline(app.EMCenter, sprintf('1A:SK %d', adjustedAzimuthAngle));
                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                    while status ~= 1
                        drawnow;

                        % Check if the user requested the test measurement to stop
                        if app.StopRequested
                            writeline(app.EMCenter, '1A:ST');
                            pause(1);
                            break;
                        end
                        
                        status = writeread(app.EMCenter,"1A:*OPC?");
                        status = str2double(strtrim(status));
                    end

                    % Exit the outer loop as well if stop was requested
                    if app.StopRequested
                        break;
                    end

                    % Get S-Parameters and Frequencies from VNA
                    [SParameters, VNAFrequencies] = measureSParameters(app.VNA, 2, startFrequency, endFrequency, sweepPoints); 

                    if ~isempty(app.ReferenceGainFile)
                        Gain_dBi = measureAntennaGain(VNAFrequencies, SParameters{2}, app.setupSpacing, ReferenceGain, ReferenceFreqs);
                    else
                        Gain_dBi = measureAntennaGain(VNAFrequencies, SParameters{2}, app.setupSpacing);
                    end

                    app.AzimuthAngles(dataPts) = tableAngles(i);
                    app.TestFrequencies(dataPts) = VNAFrequencies;
                    app.S11(dataPts) = SParameters{1};
                    app.S21(dataPts) = SParameters{2};
                    app.S22(dataPts) = SParameters{3};
                    app.AntennaGain_dBi(dataPts) = Gain_dBi;
                end

                % If the measurement was stopped 
                if ~app.StopRequested
                    combinedData = [double(app.AzimuthAngles)', double(app.TestFrequencies)', double(app.AntennaGain_dBi)', double(app.S22)'];
                    combinedNames = {'Azimuth Angles (deg)', 'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
            
                    % Check if the dataset exceeds the Excel row limit
                    passedLimit = length(app.AzimuthAngles) > app.EXCEL_MAX_ROWS;
            
                    % Save the measurement data
                    saveData(combinedData, combinedNames, passedLimit);
                end

                % Return turntable to starting position
                writeline(app.EMCenter, sprintf('1A:SK %d', 0));
            catch ME
                app.displayError(ME);
            end
        end

         function resetAntennaMeasurementProperties(app)
            app.AzimuthAngles = [];
            app.TestFrequencies = [];
            app.S11 = [];
            app.S21 = [];
            app.S22 = [];
            app.AntennaGain_dBi = [];
        end

        function plotAntenna2DMeasurement(app, sweepPoints)            
            cla(app.GainvsFrequency2D);
            cla(app.ReturnLoss2D);
            cla(app.GainvsAngle2D);
            cla(app.RadiationPlot2D);

            % Specified Angle Plotting  
            angleIndices = (app.angleRange*sweepPoints+1):((app.angleRange+1)*sweepPoints);
            angleGain = app.antennaGain(angleIndices);
            angleReturnLoss = app.antennaRL(angleIndices);

            % Specified Frequency Plotting
            frequencyIndices = app.frequencyRange:sweepPoints:length(app.antennaGain);
            frequencyGain = app.antennaGain(frequencyIndices);

            % Antenna Gain vs. Frequency, at specified angle
            plot(app.GainvsFrequency2D, app.sweepFrequencies/1E9, angleGain);
            title(app.GainvsFrequency2D, sprintf('Gain vs. Frequency at %1.0f^{\\circ}', str2double(app.selectedAngle)));
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)');
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)');
            app.setAxisLimits(app.GainvsFrequency2D, angleGain);

            % Antenna Gains vs. Angles, at specified frequency.
            plot(app.GainvsAngle2D, app.azimuthAngles, frequencyGain);
            title(app.GainvsAngle2D, 'Gain vs. Angle');
            xlabel(app.GainvsAngle2D, 'Angle (degrees)');
            ylabel(app.GainvsAngle2D, 'Gain (dBi)');
            xlim(app.GainvsAngle2D, [-180 180]);
            app.setAxisLimits(app.GainvsAngle2D, frequencyGain);

            % Return Loss (dB) Plot
            plot(app.ReturnLoss2D, app.sweepFrequencies/1E9, angleReturnLoss);
            title(app.ReturnLoss2D, sprintf('Return Loss at %1.0f^{\\circ}', str2double(app.selectedAngle)));
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)');
            ylabel(app.ReturnLoss2D, 'RL (dB)');

            % 2D Radiation Pattern Plot
            polarplot(app.RadiationPlot2D, deg2rad(app.azimuthAngles), frequencyGain);
            title(app.RadiationPlot2D, sprintf('Radiation Pattern at %1.3f GHz', str2double(app.selectedFrequency)));
            thetalim(app.RadiationPlot2D, [0 360]);
            app.setAxisLimits(app.RadiationPlot2D, frequencyGain);

            improveAxesAppearance(app.GainvsFrequency2D, 'LineThickness', 2);
            improveAxesAppearance(app.ReturnLoss2D, 'LineThickness', 2);
            improveAxesAppearance(app.GainvsAngle2D, 'LineThickness', 2);
            %improveAxesAppearance(app.RadiationPlot2D);
        end

        function setAxisLimits(~, axisHandle, data)
            minData = min(data);
            maxData = max(data);
            margin = (maxData - minData) * 0.1;

            if isa(axisHandle, 'matlab.ui.control.UIAxes')
                if margin == 0
                    margin = 0.1;
                end
                ylim(axisHandle, [minData - margin, maxData + margin]);              
            elseif isa(axisHandle, 'matlab.graphics.axis.PolarAxes')
                if margin == 0
                    margin = 1; 
                end
                rlim(axisHandle, [minData - margin, maxData + margin]);
            end
        end

         function freqLabel = formatFreq(~, Frequency)
            % This function takes in a frequency value and returns it in scientific
            % notation for plotting and viewing purposes.
            if Frequency >= 1e9
                freqLabel = sprintf('%.2f GHz', Frequency / 1e9);
            elseif Frequency >= 1e6
                freqLabel = sprintf('%.2f MHz', Frequency / 1e6);
            elseif Frequency >= 1e3
                freqLabel = sprintf('%.2f kHz', Frequency / 1e3);
            else
                freqLabel = sprintf('%.2f Hz', Frequency);
            end     
        end
        
        function displayError(app, ME)
            detailedErrorMessage = sprintf('Error: %s\n\nType of Error: %s\n\nStack Trace:\n', ME.message, ME.identifier);
            
            for k = 1:length(ME.stack)
                detailedErrorMessage = sprintf('%sIn file: %s\nFunction: %s\nLine: %d\n\n', ...
                    detailedErrorMessage, ME.stack(k).file, ME.stack(k).name, ME.stack(k).line);
            end
            
            uialert(app.UIFigure, detailedErrorMessage, 'Error', 'Icon', 'warning');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Handle Application settings
            app.UIFigure.WindowState = 'maximized';
            drawnow;
            
            % Handle PA testing settings
            app.mType = 'Single';
            app.Span.Enable = 'on';
            app.CenterFrequency.Enable = 'on';
            app.EndFrequency.Enable = 'off';
            app.FrequencyStep.Enable = 'off';

            % Handle Antenna testing settings
            app.RadiationPlot2D = polaraxes(app.GridLayout3);
            app.RadiationPlot2D.Units = "pixels";
            app.RadiationPlot2D.Layout.Row = 2;
            app.RadiationPlot2D.Layout.Column = 2;  
            app.RadiationPlot2D.Title.String = '2D Radiation Pattern';

            app.AntennaCurrentSpacing.Enable = 'off';
            app.LinearSliderCurrentPosition.Enable = 'off';
            app.SpeedPresetDropDown.Enable = 'off';
            app.MovetoPositionButton.Enable = 'off';
            app.LinearSliderNewPosition.Enable = 'off';

        end

        % Button pushed function: PAScanButton
        function PAScanResources(app, event)
            instrumentNames = {'PowerSupply', 'SignalGenerator', 'SpectrumAnalyzer'};
            app.getInstrumentResources(instrumentNames)
        end

        % Button pushed function: PAConnectButton
        function PAConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu.
            % It gets that resource and attempts to connect to it.
            instrumentNames = {'PowerSupply', 'SignalGenerator', 'SpectrumAnalyzer'};
            instrumentResources = {app.PowerSupplyDropDown.Value, app.SignalGeneratorDropDown.Value, app.SpectrumAnalyzerDropDown.Value};
            app.connectInstrument(instrumentNames, instrumentResources);    
        end

        % Value changed function: MeasurementDropDown
        function PAMeasurementType(app, event)
            type = app.MeasurementDropDown.Value;
            app.mType = string(type);
            if strcmp(app.mType, 'Sweep')
                app.Span.Enable = 'off';
                app.CenterFrequency.Enable = 'off';
                app.EndFrequency.Enable = 'on';
                app.FrequencyStep.Enable = 'on';
            elseif strcmp(app.mType, 'Single')
                app.Span.Enable = 'on';
                app.CenterFrequency.Enable = 'on';
                app.EndFrequency.Enable = 'off';
                app.FrequencyStep.Enable = 'off';
            end
        end

        % Button pushed function: StartPAMeasurement
        function PAStartMeasurement(app, event)
            app.runPAMeasurement(app.mType)
        end

        % Button pushed function: PlotPAData
        function PAPlotMeasurement(app, event)
            if strcmp(app.mType, 'Sweep')
                app.plotPASweepMeasurement();
            else
                app.plotPASingleMeasurement();
            end
            %app.PlotPAData.Enable = 'off';
        end

        % Button pushed function: SavePAData
        function PASaveData(app, event)
            if strcmp(app.mType, 'Sweep')
                numFreq = length(app.inputFrequencySweep);
                maxLength = max(length(app.inputPdBmSweep));
                paddedFreq = NaN(maxLength, numFreq);
                for i = 1:numFreq
                    paddedFreq(1,i) = app.inputFrequencySweep(i);
                end
                transpose(paddedFreq);
    
                combinedData = [paddedFreq, app.inputPdBmSweep', app.outputPdBm', app.DCPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = cell(1, size(combinedData, 2));
                parameterNames = {'Frequency (Hz)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
    
                for i = 1:numFreq
                    combinedNames{i} = sprintf('%s [%d]', parameterNames{1}, i);
                end
    
                combinedNames{numFreq + 1} = 'Input Power (dBm)';
    
                index = numFreq + 2;
    
                for k = 2:numel(parameterNames)
                    for j = 1:numFreq
                        combinedNames{index} = sprintf('%s [%d]', parameterNames{k}, j);
                        index = index + 1;
                    end
                end
            else
                maxLength = max(length(app.inputPdBmSingle));
                paddedFreq = nan(maxLength, 1);
                paddedFreq(1:length(app.inputFrequencySingle)) = app.inputFrequencySingle;
                transpose(paddedFreq)
                
                combinedData = [paddedFreq, app.inputPdBmSingle', app.outputPdBm', app.DCPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = {'Frequency (Hz)', 'Input Power (dBm)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
            end

            try
                saveData(combinedData, combinedNames);
            catch ME
                 uialert(app.UIFigure, 'Failed to save data.', 'Error', 'Icon', 'warning');
            end 
        end

        % Button pushed function: LoadPAData
        function PALoadData(app, event)
            try
                disp(app.mType)
                combinedData = loadData('PA');
                if ~isempty(combinedData) 
                    if strcmp(app.mType, 'Sweep')
                        app.inputFrequencySweep = combinedData.FrequencyHz';
                        app.inputPdBmSweep = combinedData.InputPowerdBm';
                        app.outputPdBm = combinedData.OutputPowerdBm';
                        app.DCPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    else
                        app.inputFrequencySingle = unique(combinedData.FrequencyHz)';
                        app.inputPdBmSingle = combinedData.InputPowerdBm';
                        app.outputPdBm = combinedData.OutputPowerdBm';
                        app.DCPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    end
                end
            catch ME
                uialert(app.UIFigure, 'Failed to load data.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: AntennaScanButton
        function AntennaScanResources(app, event)
            % This function scans for available VISA resources (instruments connected via VISA protocol) and populates the dropdown 
            % menus for user selection. This functionality is triggered by the user pressing the Scan button in the antenna section 
            % of the application.

            instrumentNames = {'VNA', 'EMCenter'};
            try
                app.getInstrumentResources(instrumentNames)
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: AntennaConnectButton
        function AntennaConnectInstruments(app, event)
            % This function handles the connection of the application to the antenna measurement instruments
            % (Vector Network Analyzer (VNA) and EMCenter). The user selects the appropriate instrument addresses via a dropdown menu, 
            % and the function uses these selections to establish connections. If successful, the instruments are ready for subsequent 
            % commands and data acquisition.      
            
            instrumentNames = {'VNA', 'EMCenter'};
            try
                instrumentResources = {'TCPIP0::192.168.1.113::hislip0::INSTR', 'TCPIP0::192.168.2.150::inst0::INSTR'};
                %instrumentResources = {app.VNADropDown.Value, app.EMCenterDropDown.Value};
                app.connectInstrument(instrumentNames, instrumentResources);   
            catch ME
                app.displayError(ME);
            end
        end

        % Button down function: LinearSliderTab
        function LinearSliderConnect(app, event)
            % This function establishes a connection between the application and the linear slider hardware via TCP/IP. It ensures that 
            % a connection is made only if none exists already, it retrieves the current slider position, and updates the relevant GUI components
            % to reflect the connection status and enable user interactions.

            if isempty(app.LinearSlider)
                try 
                    % Initialize TCP client connection
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';

                    % Get current position from Linear Slider
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = 2 + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                    app.setupSpacing = app.AntennaCurrentSpacing.Value;

                    % Enable Linear Slider GUI interface
                    app.AntennaCurrentSpacing.Enable = 'on';
                    app.LinearSliderCurrentPosition.Enable = 'on';
                    app.SpeedPresetDropDown.Enable = 'on';
                    app.MovetoPositionButton.Enable = 'on';
                    app.LinearSliderNewPosition.Enable = 'on';
                catch ME
                    app.displayError(ME);
                end
            end
        end

        % Value changed function: SpeedPresetDropDown
        function LinearSliderChangeSpeed(app, event)
            % This function updates the movement speed of the linear slider by sending a speed preset command to the hardware. The speed 
            % settings are predefined as presets, with values ranging from 1 (slowest) to 8 (fastest). The selected speed preset is read 
            % from the application's dropdown menu and applied directly to the slider.
            
            try 
                % Send command to linear slider to change its speed
                speedPreset = str2double(app.SpeedPresetDropDown.Value);
                writeline(app.LinearSlider, sprintf('AXIS1:S%d', speedPreset));
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoPositionButton
        function LinearSliderChangePosition(app, event)
            % This function updates the position the linear slider and dynamically adjusts associated parameters based on the slider's movement. 
            % It continuously monitors the slider's status to ensure the desired position is reached and updates the application's calculated 
            % spacing values in real-time.
            
            try
                % Send command to linear slider to move to the specified position
                writeline(app.LinearSlider, sprintf('AXIS1:SK %d', app.LinearSliderNewPosition.Value));
                motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                
                % Continuously update the setup spacing until motion is complete
                while strcmp(motion, '+1') || strcmp(motion, '-1')
                    motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    
                    sliderPosition = app.LinearSliderCurrentPosition.Value / 100; 
                    baseSpacing = 2 - sliderPosition;
                    totalSpacing = baseSpacing + app.offsetSpacing;
                    
                    % Subtract antenna physical size if provided
                    if ~isempty(app.AntennaPhysicalSize.Value)
                        totalSpacing = totalSpacing - app.AntennaPhysicalSize.Value / 100; % Convert to meters
                    end
                    
                    % Update the displayed spacing
                    app.AntennaCurrentSpacing.Value = totalSpacing;
                    
                    % Optional: add a small delay to prevent overwhelming the system
                    pause(0.1);
                end
        
                app.setupSpacing = app.AntennaCurrentSpacing.Value;
                disp(['Final setup spacing: ', num2str(app.setupSpacing), ' meters']);
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopLinearSliderButton
        function LinearSliderStopMovement(app, event)
            % This function sends a stop command to halt the movement of the linear slider. It is triggered 
            % when the user presses the stop button in the linear slider settings menu. 

            try
                writeline(app.LinearSlider, 'AXIS1:STOP')
            catch ME
                app.displayError(ME);
            end
        end

        % Value changed function: TableSpeedSlider
        function TableChangeSpeed(app, event)
           % This function updates the turn-table speed value in the application when the user adjusts the TableSpeedSlider. While this 
           % function does not directly update the speed of the hardware, it ensures that the application's internal settings reflect the new speed. The updated value will be used in subsequent operations involving the turntable, allowing the user to adjust the speed without immediate hardware changes.
 

            try
                app.TableSpeedSlider.Value = app.TableSpeedSlider.Value; 
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoAngleButton
        function TableChangeAngle(app, event)
            % This function initiates the process of changing the turn-table angle to a new value. The function sends a command 
            % to set the turn-table's speed and a command to adjust the turn-table's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so.  
            
            try
                app.StopRequested = false;
                writeline(app.EMCenter, sprintf('1A:SPEED %d', app.TableSpeedSlider.Value));
                writeline(app.EMCenter, sprintf('1A:SK %d', app.TableNewAngle.Value));
                status = writeread(app.EMCenter,"1A:*OPC?");
                status = str2double(strtrim(status));

                while status ~= 1
                    drawnow;

                    if app.StopRequested
                        break;
                    end

                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTableButton
        function TableStopMovement(app, event)
            % This function is executed when the user requests to stop the turn-table movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the table to halt any ongoing operation. 
            
            app.StopRequested = true;
            disp('User requested turn-table to stop movement.');
            
            try
                writeline(app.EMCenter, '1A:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MoveTowertoAngleButton
        function TowerChangeAngle(app, event)
            % This function initiates the process of changing the tower's angle to a new value. The function sends a command 
            % to set the tower's speed to a default value of 20 and a command to adjust the tower's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so. 
            
            try
                app.StopRequested = false;
                writeline(app.EMCenter, '1B:SPEED 20');
                writeline(app.EMCenter, sprintf('1B:SK %d', app.TowerNewAngle.Value));
            
                while true                    
                    drawnow;
                    
                    if app.StopRequested
                        break;
                    end

                    pause(0.1);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTowerButton
        function TowerStopMovement(app, event)
            % This function is executed when the user requests to stop the tower movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the tower to halt any ongoing operation. 
            
            app.StopRequested = true;
            disp('User requested tower to stop movement.');

            try
                writeline(app.EMCenter, '1B:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StartTestButton
        function AntennaStartMeasurement(app, event)
            % This function validates the user inputs for the VNA measurement settings and performs the selected measurement 
            % type (either "Gain Characterization" or "Pattern Measurement"). It ensures that the start and end frequencies are set and distinct, 
            % the number of sweep points is specified, and the antenna physical size is provided. Based on the chosen measurement type, the 
            % function either calls the getBoresightGain method for gain characterization or the get2DAntennaGain method for 2D antenna pattern measurement. 
            
            try
                if isempty(app.VNAStartFrequency.Value) || isempty(app.VNAEndFrequency.Value) ||(app.VNAStartFrequency.Value == app.VNAEndFrequency.Value) 
                    error('Invalid frequency values, start and end frequencies must be set and different.');
                elseif isempty(app.VNASweepPoints.Value)
                    error('Number of sweep points not set.');
                elseif isempty(app.AntennaPhysicalSize.Value)
                    error('Antenna physical dimension offset not set');
                end
            
                startFrequency = app.VNAStartFrequency.Value * 1E6;
                endFrequency = app.VNAEndFrequency.Value * 1E6;
                sweepPoints = app.VNASweepPoints.Value;
            
                switch app.VNAMeasurement.Value
                    case 'Gain Characterization'
                        app.getBoresightGain(startFrequency, endFrequency, sweepPoints);
                    case 'Pattern Measurement'
                        app.get2DAntennaGain(startFrequency, endFrequency, sweepPoints);
                end
           
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTestButton
        function AntennaStopMeasurement(app, event)
            % This function handles the user's request to stop the ongoing 2D radiation pattern measurement. When the stop request is triggered,
            % the function terminates the measurement process, checks if any data has been recorded, and prompts the user to decide whether to save 
            % the collected data. If the user opts to save, the function prepares the data and saves it. 

            app.StopRequested = true;
            disp('User requested to stop the test. Terminating measurement and saving data if necessary.');
            
            try
                writeline(app.EMCenter, '1A:ST');
                pause(1);

                % Check if any data has been recorded thus far.
                if ~isempty(app.AzimuthAngles) && any(app.AzimuthAngles)
                    % Identify valid data points since the arrays were
                    % initialized with 0's.
                    validIndices = app.TestFrequencies > 0;
                
                    % Filter data arrays
                    filteredAzimuthAngles = app.AzimuthAngles(validIndices);
                    filteredFrequencies = app.TestFrequencies(validIndices);
                    filteredAntennaGain = app.AntennaGain_dBi(validIndices);
                    filteredS22 = app.S22(validIndices);
                
                    % Combine filtered data
                    combinedData = [double(filteredAzimuthAngles)', double(filteredFrequencies)', double(filteredAntennaGain)', double(filteredS22)'];
                
                    % Handle the case where no valid data exists
                    if isempty(combinedData)
                        disp('No valid data was recorded. Skipping save operation.');
                        return;
                    end
                
                    % Ask the user if they want to save the data
                    question = 'Do you want to save collected data?';
                    choice = uiconfirm(app.UIFigure, question, 'Stop Test', 'Options', {'Yes', 'No'}, 'DefaultOption', 1);
                
                    if strcmp(choice, 'Yes')
                        % Prepare data for the saveData function
                        combinedNames = {'Azimuth Angles (deg)', 'Frequency (Hz)', 'Gain (dBi)', 'Return Loss (dB)'};
                        passedLimit = length(app.AzimuthAngles) > app.EXCEL_MAX_ROWS;
                        saveData(combinedData, combinedNames, passedLimit);
                    end
                end
                
                % Reset antenna measurement properties for efficient data
                % handling.
                app.resetAntennaMeasurementProperties();
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotResultsButton
        function AntennaPlotMeasurement(app, event)
            % This function is responsible for plotting the 2D antenna gain measurement results, providing 
            % the user with visual insights into the measurement data. 

            sweepPts = length(app.sweepFrequencies);
            app.plotAntenna2DMeasurement(sweepPts);
        end

        % Button pushed function: LoadResultsButton
        function AntennaLoadData(app, event)
            % This function is responsible for loading an antenna gain file into the application. 
            % The file should adhere to the application's predefined column headers for antenna gain data:
            %
            %       Azimuth Angles (deg)
            %       Frequency (Hz)
            %       Gain (dBi) (Gain in dBi)
            %       Return Loss (dB) 
            % 
            % If any fields are missing, an error is raised to inform the user. Upon successful loading, 
            % the data is extracted and used to populate relevant fields within the application, 
            % including dropdown menus for selecting specific angles and frequencies for plotting.

            try 
                % Load the antenna gain file
                combinedAntennaData = loadData('Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(combinedAntennaData))
                    return;
                end

                % Check each required field and add to the list if missing.
                missingFields = {};
                if ~isfield(combinedAntennaData, 'AzimuthAnglesdeg')
                    missingFields{end+1} = 'Azimuth Angles (deg)';
                end
                if ~isfield(combinedAntennaData, 'FrequencyHz')
                    missingFields{end+1} = 'Frequency (Hz)';
                end
                if ~isfield(combinedAntennaData, 'GaindBi')
                    missingFields{end+1} = 'Gain (dBi)';
                end
                if ~isfield(combinedAntennaData, 'ReturnLossdB')
                    missingFields{end+1} = 'Return Loss (dB)';
                end
            
                % If any fields are missing, raise an error telling the
                % user which field is missing. 
                if ~isempty(missingFields)
                    error(['The antenna gain file is missing the following required field(s): ', strjoin(missingFields, ', ')]);
                end

                % Extract data from the file and load it into the
                % application if all required fields are found.
                app.azimuthAngles = unique(combinedAntennaData.AzimuthAnglesdeg)';
                app.sweepFrequencies = unique(combinedAntennaData.FrequencyHz)';
                app.antennaGain = combinedAntennaData.GaindBi';
                app.antennaRL = combinedAntennaData.ReturnLossdB';

                % Populate dropdown menus, with the angles and frequencies
                % from the gain file so user can select which specific
                % angle or frequency to plot.
                app.PlottingAngleDropDown.Items = string(app.azimuthAngles);
                app.PlottingFrequencyDropDown.Items = string(app.sweepFrequencies/1E9);

                % Set default values for the angle and frequency.
                app.selectedAngle = app.PlottingAngleDropDown.Items(1);
                app.selectedFrequency = app.PlottingFrequencyDropDown.Items(1);

                % Set default values for the angle and frequency ranges, so
                % the plots only display the necessary data. 
                app.angleRange = 0;
                app.frequencyRange = 1;
            catch ME    
                app.displayError(ME);
            end
        end

        % Value changed function: PlottingAngleDropDown
        function AntennaSelectedAngleValue(app, event)
            % This function automatically adjusts the angle range for plotting based on the user's selected angle from the dropdown menu.
            % It retrieves the selected angle and then calculates the corresponding index in the list of available angles.
            % The angle range is updated to reflect the index of the selected angle, which is used to determine which data to plot.

            app.selectedAngle = app.PlottingAngleDropDown.Value;   
            app.angleRange = find(strcmp(app.PlottingAngleDropDown.Items, app.selectedAngle)) - 1;
        end

        % Value changed function: PlottingFrequencyDropDown
        function AntennaSelectedFrequencyValue(app, event)
            % This function automatically adjusts the frequency range for plotting based on the user's selected frequency from the dropdown menu.
            % It retrieves the selected frequency and calculates the corresponding index in the list of available frequencies. 
            % The frequency range is updated to reflect the index of the selected frequency, which is used to determine which data to plot.

            app.selectedFrequency = app.PlottingFrequencyDropDown.Value;
            app.frequencyRange = find(strcmp(app.PlottingFrequencyDropDown.Items, app.selectedFrequency));
        end

        % Button pushed function: BrowseReferenceFileButton
        function LoadReferenceGainFile(app, event)
            % The reference gain file must adhere to the application's specified column headers format:
            %
            %       Frequency (Hz)
            %       Gain (dBi)
            %       Return Loss (dB) 
            % 
            % The function verifies that all required fields are present in the file, and if any are missing, it raises an error. 
            % If the file is valid, the path to the loaded reference gain file is displayed to the user.

            try 
                % Load the reference gain file
                app.ReferenceGainFile = loadData('Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(app.ReferenceGainFile))
                    return;
                end
            
                % Check each required field and add to the list if missing.
                missingFields = {};
                if ~isfield(app.ReferenceGainFile, 'FrequencyHz')
                    missingFields{end+1} = 'Frequency (Hz)';
                end
                if ~isfield(app.ReferenceGainFile, 'GaindBi')
                    missingFields{end+1} = 'Gain (dBi)';
                end
                if ~isfield(app.ReferenceGainFile, 'ReturnLossdB')
                    missingFields{end+1} = 'Return Loss (dB)';
                end
            
                % If any fields are missing, raise an error telling the
                % user which field is missing. 
                if ~isempty(missingFields)
                    error(['The reference gain file is missing the following required field(s): ', strjoin(missingFields, ', ')]);
                end

                % If the file presents no errors, the file path is loaded
                % into the file, and displayed to the user.
                app.ReferenceGainFileField.Value = evalin('base', 'loadedFilePath');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotReferenceGainButton
        function PlotReferenceGainFile(app, event)
            % This function plots the reference gain data that has been loaded into the application. It extracts the frequency, 
            % antenna gain, and return loss from the reference gain file and displays them on the corresponding plots for the user.

            try
                % Clear current plots.
                cla(app.GainvsFrequencyBoresightGain);
                cla(app.ReturnLossBoresightGain);

                % Extract antenna measurement parameters from the file.
                Frequencies = app.ReferenceGainFile.FrequencyHz / 1E9;
                AntennaGain =  app.ReferenceGainFile.GaindBi;
                ReturnLoss =  app.ReferenceGainFile.ReturnLossdB;

                % Plot the parameters onto the plots.
                plot(app.GainvsFrequencyBoresightGain, Frequencies, AntennaGain);
                plot(app.ReturnLossBoresightGain, Frequencies, ReturnLoss);
            catch ME
                app.displayError(ME);
            end
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            % This function handles the app's closure behavior. When the user attempts to close the app, the function prompts them 
            % with a confirmation dialog. If the user confirms the closure, the function will clean up by closing all active instrument 
            % connections. If the user cancels the closure, the function does nothing and returns to the app.

            InstrumentConnections = {app.PowerSupply, app.SignalGenerator, app.SpectrumAnalyzer, app.VNA, app.EMCenter, app.LinearSlider};
            question = 'Close ARES App?';
            choice = uiconfirm(app.UIFigure, question, 'Confirm Close', 'Options', {'Yes', 'No'});

            switch choice
                case 'Yes'
                    for i = 1:numel(InstrumentConnections)
                        if ~isempty(InstrumentConnections{i})
                            delete(InstrumentConnections{i});
                        end
                    end
                    app.delete
                case 'No'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.GridLayout19 = uigridlayout(app.UIFigure);
            app.MainTabs = uitabgroup(app.GridLayout19);
            app.PowerAmplifierTab = uitab(app.MainTabs);
            app.PowerAmplifierTab.Title = 'Power Amplifier ';
            app.GridLayout18 = uigridlayout(app.PowerAmplifierTab);
            app.ResultsTabs = uitabgroup(app.GridLayout18);
            app.SingleTab = uitab(app.ResultsTabs);
            app.SingleTab.Title = 'Single';
            app.GridLayout16 = uigridlayout(app.SingleTab);
            app.SingleGain = uiaxes(app.GridLayout16);
            title(app.SingleGain, 'Gain, DE, PAE vs. Output Power')
            xlabel(app.SingleGain, 'Output Power (dBm)')
            zlabel(app.SingleGain, 'Z')
            app.SweepTab = uitab(app.ResultsTabs);
            app.SweepTab.Title = 'Sweep';
            app.GridLayout2 = uigridlayout(app.SweepTab);
            app.SweepGain = uiaxes(app.GridLayout2);
            title(app.SweepGain, 'Gain vs. Output Power')
            xlabel(app.SweepGain, 'Output Power (dBm)')
            ylabel(app.SweepGain, 'Gain (dB)')
            zlabel(app.SweepGain, 'Z')
            app.SweepPeakGain = uiaxes(app.GridLayout2);
            title(app.SweepPeakGain, 'Peak DE and PAE')
            xlabel(app.SweepPeakGain, 'Frequency (GHz)')
            ylabel(app.SweepPeakGain, 'Efficiency (%)')
            zlabel(app.SweepPeakGain, 'Z')
            app.SweepCompressionPoints = uiaxes(app.GridLayout2);
            title(app.SweepCompressionPoints, 'Peak Gain')
            xlabel(app.SweepCompressionPoints, 'Frequency (GHz)')
            ylabel(app.SweepCompressionPoints, 'Gain (dB)')
            zlabel(app.SweepCompressionPoints, 'Z')
            app.SweepPeakDEPAE = uiaxes(app.GridLayout2);
            title(app.SweepPeakDEPAE, 'Saturation Power and Compression Points')
            xlabel(app.SweepPeakDEPAE, 'Frequency (GHz)')
            ylabel(app.SweepPeakDEPAE, 'Output Power (dBm)')
            zlabel(app.SweepPeakDEPAE, 'Z')
            app.MeasurementPanel = uipanel(app.GridLayout18);
            app.MeasurementPanel.Title = 'Measurement';
            app.GridLayout12 = uigridlayout(app.MeasurementPanel);
            app.StartPAMeasurement = uibutton(app.GridLayout12, 'push');
            app.StartPAMeasurement.ButtonPushedFcn = createCallbackFcn(app, @PAStartMeasurement, true);
            app.StartPAMeasurement.Text = 'Start Test';
            app.LoadPAData = uibutton(app.GridLayout12, 'push');
            app.LoadPAData.ButtonPushedFcn = createCallbackFcn(app, @PALoadData, true);
            app.LoadPAData.Text = 'Load Test';
            app.SavePAData = uibutton(app.GridLayout12, 'push');
            app.SavePAData.ButtonPushedFcn = createCallbackFcn(app, @PASaveData, true);
            app.SavePAData.Text = 'Save Test';
            app.PlotPAData = uibutton(app.GridLayout12, 'push');
            app.PlotPAData.ButtonPushedFcn = createCallbackFcn(app, @PAPlotMeasurement, true);
            app.PlotPAData.Text = 'Plot Results';
            app.MeasurementDropDownLabel = uilabel(app.GridLayout12);
            app.MeasurementDropDownLabel.Text = 'Measurement';
            app.MeasurementDropDown = uidropdown(app.GridLayout12);
            app.MeasurementDropDown.Items = {'Single', 'Sweep'};
            app.MeasurementDropDown.ValueChangedFcn = createCallbackFcn(app, @PAMeasurementType, true);
            app.MeasurementDropDown.Value = 'Single';
            app.PASettingsPanel = uitabgroup(app.GridLayout18);
            app.PowerSupplyTab = uitab(app.PASettingsPanel);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.GridLayout11 = uigridlayout(app.PowerSupplyTab);
            app.SupplyVoltageVEditField = uilabel(app.GridLayout11);
            app.SupplyVoltageVEditField.Text = 'DC Voltage Start (V)';
            app.SupplyVoltage = uieditfield(app.GridLayout11, 'numeric');
            app.SupplyVoltageStopVEditField = uilabel(app.GridLayout11);
            app.SupplyVoltageStopVEditField.Text = 'DC Voltage Stop (V)';
            app.SupplyVoltageStop = uieditfield(app.GridLayout11, 'numeric');
            app.SupplyVoltageStepVEditField = uilabel(app.GridLayout11);
            app.SupplyVoltageStepVEditField.Text = 'DC Voltage Step (V)';
            app.SupplyVoltageStep = uieditfield(app.GridLayout11, 'numeric');
            app.ModeDropDownLabel = uilabel(app.GridLayout11);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout11);
            app.ModeDropDown.Items = {'Single Supply', 'Dual Supply', 'Quad Supply'};
            app.ModeDropDown.Value = 'Dual Supply';
            app.SupplyDropDownLabel = uilabel(app.GridLayout11);
            app.SupplyDropDownLabel.Text = 'Supply';
            app.SupplyDropDown = uidropdown(app.GridLayout11);
            app.SupplyDropDown.Items = {'1', '2', '3', '4'};
            app.SupplyDropDown.Value = '1';
            app.SignalGeneratorTab = uitab(app.PASettingsPanel);
            app.SignalGeneratorTab.Title = 'Signal Generator';
            app.GridLayout13 = uigridlayout(app.SignalGeneratorTab);
            app.EndFrequencyMHzLabel = uilabel(app.GridLayout13);
            app.EndFrequencyMHzLabel.Text = 'End Frequency (MHz)';
            app.StartFrequencyMHzLabel = uilabel(app.GridLayout13);
            app.StartFrequencyMHzLabel.Text = 'Start Frequency (MHz)';
            app.FrequencyStepMHzLabel = uilabel(app.GridLayout13);
            app.FrequencyStepMHzLabel.Text = 'Frequency Step (MHz)';
            app.StartPowerdBmEditFieldLabel = uilabel(app.GridLayout13);
            app.StartPowerdBmEditFieldLabel.Text = 'Start Power (dBm)';
            app.EndPowerdBmEditFieldLabel = uilabel(app.GridLayout13);
            app.EndPowerdBmEditFieldLabel.Text = 'End Power (dBm)';
            app.PowerStepdBmEditField = uilabel(app.GridLayout13);
            app.PowerStepdBmEditField.Text = 'Power Step (dBm)';
            app.StartFrequency = uieditfield(app.GridLayout13, 'numeric');
            app.EndFrequency = uieditfield(app.GridLayout13, 'numeric');
            app.FrequencyStep = uieditfield(app.GridLayout13, 'numeric');
            app.StartPower = uieditfield(app.GridLayout13, 'numeric');
            app.EndPower = uieditfield(app.GridLayout13, 'numeric');
            app.PowerStep = uieditfield(app.GridLayout13, 'numeric');
            app.SpectrumAnalyzerTab = uitab(app.PASettingsPanel);
            app.SpectrumAnalyzerTab.Title = 'Spectrum Analyzer';
            app.GridLayout14 = uigridlayout(app.SpectrumAnalyzerTab);
            app.ReferenceLeveldBEditField = uilabel(app.GridLayout14);
            app.ReferenceLeveldBEditField.Text = 'Reference Level (dB)';
            app.SweepPointsEditField = uilabel(app.GridLayout14);
            app.SweepPointsEditField.Text = 'Sweep Points';
            app.SpanMHzEditField = uilabel(app.GridLayout14);
            app.SpanMHzEditField.Text = 'Span (MHz)';
            app.ReferenceLevel = uieditfield(app.GridLayout14, 'numeric');
            app.SweepPoints = uieditfield(app.GridLayout14, 'numeric');
            app.Span = uieditfield(app.GridLayout14, 'numeric');
            app.CenterFrequencyMHzEditField = uilabel(app.GridLayout14);
            app.CenterFrequencyMHzEditField.Text = 'Center Frequency (MHz)';
            app.CenterFrequency = uieditfield(app.GridLayout14, 'numeric');
            app.InstrumentConnectionsPanel = uipanel(app.GridLayout18);
            app.InstrumentConnectionsPanel.Title = 'Instrument Connections';
            app.GridLayout15 = uigridlayout(app.InstrumentConnectionsPanel);
            app.PAScanButton = uibutton(app.GridLayout15, 'push');
            app.PAScanButton.ButtonPushedFcn = createCallbackFcn(app, @PAScanResources, true);
            app.PAScanButton.Text = 'Scan';
            app.PAConnectButton = uibutton(app.GridLayout15, 'push');
            app.PAConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PAConnectInstruments, true);
            app.PAConnectButton.Text = 'Connect';
            app.PowerSupplyDropDownLabel = uilabel(app.GridLayout15);
            app.PowerSupplyDropDownLabel.Text = 'Power Supply';
            app.PowerSupplyDropDown = uidropdown(app.GridLayout15);
            app.PowerSupplyDropDown.Items = {'Address'};
            app.PowerSupplyDropDown.Value = 'Address';
            app.SignalGeneratorDropDownLabel = uilabel(app.GridLayout15);
            app.SignalGeneratorDropDownLabel.Text = 'Signal Generator';
            app.SignalGeneratorDropDown = uidropdown(app.GridLayout15);
            app.SignalGeneratorDropDown.Items = {'Address'};
            app.SignalGeneratorDropDown.Value = 'Address';
            app.SpectrumAnalyzerDropDownLabel = uilabel(app.GridLayout15);
            app.SpectrumAnalyzerDropDownLabel.Text = 'Spectrum Analyzer';
            app.SpectrumAnalyzerDropDown = uidropdown(app.GridLayout15);
            app.SpectrumAnalyzerDropDown.Items = {'Address'};
            app.SpectrumAnalyzerDropDown.Value = 'Address';
            app.AntennaTab = uitab(app.MainTabs);
            app.AntennaTab.Title = 'Antenna ';
            app.GridLayout17 = uigridlayout(app.AntennaTab);
            app.AntennaPattern = uitabgroup(app.GridLayout17);
            app.MeasurementAntennaGainTab = uitab(app.AntennaPattern);
            app.MeasurementAntennaGainTab.Title = 'Measurement Antenna Gain';
            app.GridLayout3_2 = uigridlayout(app.MeasurementAntennaGainTab);
            app.GainvsFrequencyBoresightGain = uiaxes(app.GridLayout3_2);
            title(app.GainvsFrequencyBoresightGain, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequencyBoresightGain, 'Frequency (GHz)')
            ylabel(app.GainvsFrequencyBoresightGain, 'Gain (dBi)')
            zlabel(app.GainvsFrequencyBoresightGain, 'Z')
            app.ReturnLossBoresightGain = uiaxes(app.GridLayout3_2);
            title(app.ReturnLossBoresightGain, 'Return Loss')
            xlabel(app.ReturnLossBoresightGain, 'Frequency (GHz)')
            ylabel(app.ReturnLossBoresightGain, 'RL (dB)')
            zlabel(app.ReturnLossBoresightGain, 'Z')
            app.PlotReferenceGainButton = uibutton(app.GridLayout3_2, 'push');
            app.PlotReferenceGainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotReferenceGainFile, true);
            app.PlotReferenceGainButton.Text = 'Plot Reference Gain';
            app.ReferenceGainFileField = uieditfield(app.GridLayout3_2, 'text');
            app.ReferenceGainFileField.Editable = 'off';
            app.BrowseReferenceFileButton = uibutton(app.GridLayout3_2, 'push');
            app.BrowseReferenceFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceGainFile, true);
            app.BrowseReferenceFileButton.Text = 'Browse Reference File';
            app.DPatternMeasurementTab = uitab(app.AntennaPattern);
            app.DPatternMeasurementTab.Title = '2D Pattern Measurement';
            app.GridLayout3 = uigridlayout(app.DPatternMeasurementTab);
            app.ReturnLoss2D = uiaxes(app.GridLayout3);
            title(app.ReturnLoss2D, 'Return Loss')
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)')
            ylabel(app.ReturnLoss2D, 'RL (dB)')
            zlabel(app.ReturnLoss2D, 'Z')
            app.GainvsFrequency2D = uiaxes(app.GridLayout3);
            title(app.GainvsFrequency2D, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)')
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)')
            zlabel(app.GainvsFrequency2D, 'Z')
            app.GainvsAngle2D = uiaxes(app.GridLayout3);
            title(app.GainvsAngle2D, 'Gain vs. Angle')
            xlabel(app.GainvsAngle2D, 'Angle (°)')
            ylabel(app.GainvsAngle2D, 'Gain (dBi)')
            zlabel(app.GainvsAngle2D, 'Z')
            app.AntennaSettings = uitabgroup(app.GridLayout17);
            app.VNATab = uitab(app.AntennaSettings);
            app.VNATab.Title = 'VNA';
            app.GridLayout21 = uigridlayout(app.VNATab);
            app.StartFrequectSpanMHzLabel_15 = uilabel(app.GridLayout21);
            app.StartFrequectSpanMHzLabel_15.Text = 'Start Frequency';
            app.EndFrequencyMHzLabel_6 = uilabel(app.GridLayout21);
            app.EndFrequencyMHzLabel_6.Text = 'End Frequency';
            app.VNAMeasurement = uidropdown(app.GridLayout21);
            app.VNAMeasurement.Items = {'Gain Characterization', 'Pattern Measurement'};
            app.VNAMeasurement.Value = 'Gain Characterization';
            app.VNASweepPoints = uieditfield(app.GridLayout21, 'numeric');
            app.VNASweepPoints.Limits = [0 Inf];
            app.VNASweepPoints.AllowEmpty = 'on';
            app.VNASweepPoints.Value = [];
            app.VNAStartFrequency = uieditfield(app.GridLayout21, 'numeric');
            app.VNAStartFrequency.Limits = [0 Inf];
            app.VNAStartFrequency.ValueDisplayFormat = '%11.4g (MHz)';
            app.VNAStartFrequency.AllowEmpty = 'on';
            app.VNAStartFrequency.Value = [];
            app.VNAEndFrequency = uieditfield(app.GridLayout21, 'numeric');
            app.VNAEndFrequency.Limits = [0 Inf];
            app.VNAEndFrequency.ValueDisplayFormat = '%11.4g (MHz)';
            app.VNAEndFrequency.AllowEmpty = 'on';
            app.VNAEndFrequency.Value = [];
            app.MeasurementDropDown_2Label = uilabel(app.GridLayout21);
            app.MeasurementDropDown_2Label.Text = 'Measurement';
            app.SweepPointsLabel = uilabel(app.GridLayout21);
            app.SweepPointsLabel.Text = 'Sweep Points';
            app.Table = uitab(app.AntennaSettings);
            app.Table.Title = 'Table';
            app.GridLayout8 = uigridlayout(app.Table);
            app.StartFrequectSpanMHzLabel_11 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_11.Text = 'Start Angle ';
            app.TableStartAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableStartAngle.Limits = [-180 180];
            app.TableStartAngle.AllowEmpty = 'on';
            app.TableStartAngle.Placeholder = '(deg)';
            app.TableStartAngle.Value = [];
            app.StartFrequectSpanMHzLabel_12 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_12.Text = 'Angle Step Size ';
            app.TableStepAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableStepAngle.AllowEmpty = 'on';
            app.TableStepAngle.Placeholder = '(deg)';
            app.TableStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_13 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_13.Text = 'End Angle ';
            app.TableEndAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableEndAngle.Limits = [-180 180];
            app.TableEndAngle.AllowEmpty = 'on';
            app.TableEndAngle.Placeholder = '(deg)';
            app.TableEndAngle.Value = [];
            app.StopTableButton = uibutton(app.GridLayout8, 'push');
            app.StopTableButton.ButtonPushedFcn = createCallbackFcn(app, @TableStopMovement, true);
            app.StopTableButton.Text = 'Stop Table';
            app.TableNewAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.TableSpeedSliderLabel = uilabel(app.GridLayout8);
            app.TableSpeedSliderLabel.Text = 'Table Speed';
            app.TableSpeedSlider = uislider(app.GridLayout8);
            app.TableSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @TableChangeSpeed, true);
            app.TableSpeedSlider.Value = 50;
            app.MovetoAngleButton = uibutton(app.GridLayout8, 'push');
            app.MovetoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TableChangeAngle, true);
            app.MovetoAngleButton.Text = 'Move to Angle';
            app.TowerTab = uitab(app.AntennaSettings);
            app.TowerTab.Title = 'Tower';
            app.GridLayout9 = uigridlayout(app.TowerTab);
            app.StartFrequectSpanMHzLabel_16 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_16.Enable = 'off';
            app.StartFrequectSpanMHzLabel_16.Text = 'Start Angle ';
            app.TowerStartAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerStartAngle.Limits = [0 360];
            app.TowerStartAngle.AllowEmpty = 'on';
            app.TowerStartAngle.Enable = 'off';
            app.TowerStartAngle.Placeholder = '(deg)';
            app.TowerStartAngle.Value = [];
            app.StartFrequectSpanMHzLabel_17 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_17.Enable = 'off';
            app.StartFrequectSpanMHzLabel_17.Text = 'Angle Step Size ';
            app.TowerStepAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerStepAngle.AllowEmpty = 'on';
            app.TowerStepAngle.Enable = 'off';
            app.TowerStepAngle.Placeholder = '(deg)';
            app.TowerStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_18 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_18.Enable = 'off';
            app.StartFrequectSpanMHzLabel_18.Text = 'End Angle ';
            app.TowerEndAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerEndAngle.Limits = [0 360];
            app.TowerEndAngle.AllowEmpty = 'on';
            app.TowerEndAngle.Enable = 'off';
            app.TowerEndAngle.Placeholder = '(deg)';
            app.TowerEndAngle.Value = [];
            app.StopTowerButton = uibutton(app.GridLayout9, 'push');
            app.StopTowerButton.ButtonPushedFcn = createCallbackFcn(app, @TowerStopMovement, true);
            app.StopTowerButton.Text = 'Stop Tower';
            app.TowerSpeedSliderLabel = uilabel(app.GridLayout9);
            app.TowerSpeedSliderLabel.Text = 'Tower Speed';
            app.TowerSpeedSlider = uislider(app.GridLayout9);
            app.TowerSpeedSlider.Value = 50;
            app.TowerNewAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerNewAngle.Limits = [-180 180];
            app.TowerNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.MoveTowertoAngleButton = uibutton(app.GridLayout9, 'push');
            app.MoveTowertoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TowerChangeAngle, true);
            app.MoveTowertoAngleButton.Text = 'Move to Angle';
            app.LinearSliderTab = uitab(app.AntennaSettings);
            app.LinearSliderTab.Title = 'Linear Slider';
            app.LinearSliderTab.ButtonDownFcn = createCallbackFcn(app, @LinearSliderConnect, true);
            app.GridLayout10 = uigridlayout(app.LinearSliderTab);
            app.StopLinearSliderButton = uibutton(app.GridLayout10, 'push');
            app.StopLinearSliderButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderStopMovement, true);
            app.StopLinearSliderButton.Text = 'Stop Linear Slider';
            app.SpeedPresetDropDownLabel = uilabel(app.GridLayout10);
            app.SpeedPresetDropDownLabel.Text = 'Speed Preset';
            app.SpeedPresetDropDown = uidropdown(app.GridLayout10);
            app.SpeedPresetDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpeedPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @LinearSliderChangeSpeed, true);
            app.SpeedPresetDropDown.Value = '1';
            app.CurrentSpacingLabel = uilabel(app.GridLayout10);
            app.CurrentSpacingLabel.Text = 'Current Spacing ';
            app.AntennaCurrentSpacing = uieditfield(app.GridLayout10, 'numeric');
            app.AntennaCurrentSpacing.ValueDisplayFormat = '%11.4g (m)';
            app.AntennaCurrentSpacing.AllowEmpty = 'on';
            app.AntennaCurrentSpacing.Editable = 'off';
            app.AntennaCurrentSpacing.Value = [];
            app.SliderPositioncmLabel = uilabel(app.GridLayout10);
            app.SliderPositioncmLabel.Text = 'Slider Current Position';
            app.LinearSliderCurrentPosition = uieditfield(app.GridLayout10, 'numeric');
            app.LinearSliderCurrentPosition.ValueDisplayFormat = '%11.4g (cm)';
            app.LinearSliderCurrentPosition.AllowEmpty = 'on';
            app.LinearSliderCurrentPosition.Editable = 'off';
            app.LinearSliderCurrentPosition.Value = [];
            app.MovetoPositionButton = uibutton(app.GridLayout10, 'push');
            app.MovetoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderChangePosition, true);
            app.MovetoPositionButton.Text = 'Move to Position';
            app.LinearSliderNewPosition = uieditfield(app.GridLayout10, 'numeric');
            app.LinearSliderNewPosition.Limits = [0 200];
            app.LinearSliderNewPosition.Placeholder = 'cm';
            app.AntennaOffsetLabel = uilabel(app.GridLayout10);
            app.AntennaOffsetLabel.Text = 'Antenna Offset ';
            app.AntennaPhysicalSize = uieditfield(app.GridLayout10, 'numeric');
            app.AntennaPhysicalSize.Limits = [0 Inf];
            app.AntennaPhysicalSize.ValueDisplayFormat = '%11.4g (cm)';
            app.AntennaPhysicalSize.AllowEmpty = 'on';
            app.AntennaPhysicalSize.Value = [];
            app.InstrumentConnectionsPanel_2 = uipanel(app.GridLayout17);
            app.InstrumentConnectionsPanel_2.Title = 'Instrument Connections';
            app.GridLayout6 = uigridlayout(app.InstrumentConnectionsPanel_2);
            app.AntennaScanButton = uibutton(app.GridLayout6, 'push');
            app.AntennaScanButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaScanResources, true);
            app.AntennaScanButton.Text = 'Scan';
            app.AntennaConnectButton = uibutton(app.GridLayout6, 'push');
            app.AntennaConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaConnectInstruments, true);
            app.AntennaConnectButton.Text = 'Connect';
            app.VNADropDownLabel = uilabel(app.GridLayout6);
            app.VNADropDownLabel.Text = 'VNA';
            app.VNADropDown = uidropdown(app.GridLayout6);
            app.VNADropDown.Items = {'Address'};
            app.VNADropDown.Value = 'Address';
            app.EMCenterDropDownLabel = uilabel(app.GridLayout6);
            app.EMCenterDropDownLabel.Text = 'EM Center';
            app.EMCenterDropDown = uidropdown(app.GridLayout6);
            app.EMCenterDropDown.Items = {'Address'};
            app.EMCenterDropDown.Value = 'Address';
            app.TestandPlotSettingsPanel = uipanel(app.GridLayout17);
            app.TestandPlotSettingsPanel.Title = 'Test and Plot Settings';
            app.GridLayout4 = uigridlayout(app.TestandPlotSettingsPanel);
            app.PlottingFrequencyDropDownLabel = uilabel(app.GridLayout4);
            app.PlottingFrequencyDropDownLabel.Text = 'Plotting Frequency';
            app.PlottingFrequencyDropDown = uidropdown(app.GridLayout4);
            app.PlottingFrequencyDropDown.Items = {'(GHz)'};
            app.PlottingFrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedFrequencyValue, true);
            app.PlottingFrequencyDropDown.Value = '(GHz)';
            app.PlottingAngleDropDownLabel = uilabel(app.GridLayout4);
            app.PlottingAngleDropDownLabel.Text = 'Plotting Angle';
            app.PlottingAngleDropDown = uidropdown(app.GridLayout4);
            app.PlottingAngleDropDown.Items = {'(°)'};
            app.PlottingAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedAngleValue, true);
            app.PlottingAngleDropDown.Value = '(°)';
            app.StartTestButton = uibutton(app.GridLayout4, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStartMeasurement, true);
            app.StartTestButton.Text = 'Start Test';
            app.PlotResultsButton = uibutton(app.GridLayout4, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaPlotMeasurement, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.LoadResultsButton = uibutton(app.GridLayout4, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaLoadData, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.StopTestButton = uibutton(app.GridLayout4, 'push');
            app.StopTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStopMeasurement, true);
            app.StopTestButton.Text = 'Stop Test';
            app.InfoTab = uitab(app.MainTabs);
            app.InfoTab.Title = 'Info';
            app.GridLayout20 = uigridlayout(app.InfoTab);
            app.Image = uiimage(app.GridLayout20);
            app.Image.ImageSource = 'ARES logo.jpg';
            app.Label = uilabel(app.GridLayout20);
            app.Label.Text = 'Automated Radio Evaluation Suite';
            app.Label_2 = uilabel(app.GridLayout20);
            app.Label_2.Text = '';
            app.V01BETARelaseLabel = uilabel(app.GridLayout20);
            app.V01BETARelaseLabel.Text = 'V0.1 BETA Relase';
            app.Hyperlink = uihyperlink(app.GridLayout20);
            app.Hyperlink.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite';
            app.Hyperlink.Text = 'GitHub Repo';
            app.AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel = uilabel(app.GridLayout20);
            app.AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel.Text = {'Author: Jose Abrahan Bolaños Vargas'; 'Mentor: Alex D. Santiago Vargas'; 'PI: Dimitrios Peroulis'; 'Adaptive Radio Electronics and Sensors (ARES) Group'};
            app.Hyperlink2 = uihyperlink(app.GridLayout20);
            app.Hyperlink2.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#pa-measurement-tutorial';
            app.Hyperlink2.Text = 'PA Measurement Tutorial';
            app.Hyperlink2_2 = uihyperlink(app.GridLayout20);
            app.Hyperlink2_2.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#antenna-measurement-tutorial';
            app.Hyperlink2_2.Text = 'Antenna Tutorial';
            app.Hyperlink2_3 = uihyperlink(app.GridLayout20);
            app.Hyperlink2_3.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite/blob/main/README.md';
            app.Hyperlink2_3.Text = 'README';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end